classdef propagationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PropagationAppUIFigure    matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        MainLayout                matlab.ui.container.GridLayout
        ParametersPanel           matlab.ui.container.Panel
        N1SliderLabel             matlab.ui.control.Label
        N1Slider                  matlab.ui.control.Slider
        N2SliderLabel             matlab.ui.control.Label
        N2Slider                  matlab.ui.control.Slider
        RSliderLabel              matlab.ui.control.Label
        RSlider                   matlab.ui.control.Slider
        VmaxSliderLabel           matlab.ui.control.Label
        VmaxSlider                matlab.ui.control.Slider
        ImmobSliderLabel          matlab.ui.control.Label
        ImmobSlider               matlab.ui.control.Slider
        ActionsPanel              matlab.ui.container.Panel
        SimulateButton            matlab.ui.control.Button
        AnimatePropagationButton  matlab.ui.control.Button
        OpenModelButton           matlab.ui.control.Button
        SaveResultsButton         matlab.ui.control.Button
        UpdatePanel               matlab.ui.container.Panel
        UpdateButton              matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        PlotLayout                matlab.ui.container.GridLayout
        PlotPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        initdata % Initial data
        data     % Simulation data
        model = 'propagationSimulator.slx'
        tu       % ToolboxUpdater
    end
    
    methods (Access = private)
        
        function plotData(app, data)
            wb = uiprogressdlg(app.PropagationAppUIFigure, 'Title', 'Plotting...',...
                'Indeterminate', true);
            propagationAnimator(data, 'plot', 'Figure', app.PlotPanel);
            close(wb)
        end
        
        function updateInit(app)
            app.initdata = initData('N1', app.N1Slider.Value, 'N2', app.N2Slider.Value,...
                'R', app.RSlider.Value, 'vmax', app.VmaxSlider.Value,...
                'immobRatio', app.ImmobSlider.Value);
            assignin('base', 'initdata', app.initdata);
            app.plotData(app.initdata);
        end
        
        function isupdate(app, isupd)
            if isupd
                app.UpdatePanel.Visible = true;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UpdatePanel.Visible = false;
            app.updateInit();
            %app.tu = VirusPropagationUpdater;
            %app.tu.isupdate(@app.isupdate, 2);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            wb = uiprogressdlg(app.PropagationAppUIFigure, 'Title', 'Simulating...',...
                'Indeterminate', true);
            out = sim(app.model);
            assignin('base', 'out', out);
            close(wb);
            app.data = out;
            app.plotData(app.data);
            app.AnimatePropagationButton.Enable = true;
            app.SaveResultsButton.Enable = true;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PropagationAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {540, 540};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: N1Slider
        function N1SliderValueChanged(app, event)
            app.N1Slider.Value = round(app.N1Slider.Value);
            app.updateInit();
        end

        % Value changed function: N2Slider
        function N2SliderValueChanged(app, event)
            app.N2Slider.Value = round(app.N2Slider.Value);
            app.updateInit();
        end

        % Button pushed function: AnimatePropagationButton
        function AnimatePropagationButtonPushed(app, event)
            propagationAnimator(app.data);
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            app.updateInit();
        end

        % Value changed function: ImmobSlider
        function ImmobSliderValueChanged(app, event)
            app.updateInit();
        end

        % Value changed function: VmaxSlider
        function VmaxSliderValueChanged(app, event)
            app.updateInit();
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            open(app.model);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            [file, fpath, fidx] = uiputfile(...
                {'*.mat','(*.mat)'}, 'Save results to file', 'data.mat');
            if isnumeric(file)
                fpath = '';
            else
                fpath = strcat(fpath, file);
            end
            a = propagationAnimator(app.data, 'silent');
            data = a.getData();
            save(fpath, 'data');
            figure(app.PropagationAppUIFigure);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.tu.ext.webrel();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PropagationAppUIFigure = uifigure('Visible', 'off');
            app.PropagationAppUIFigure.Name = 'Propagation App';
            app.PropagationAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PropagationAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MainLayout = uigridlayout(app.LeftPanel);
            app.ParametersPanel = uipanel(app.MainLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.N1SliderLabel = uilabel(app.ParametersPanel);
            app.N1SliderLabel.Text = 'N1';
            app.N1Slider = uislider(app.ParametersPanel);
            app.N1Slider.Limits = [1 10];
            app.N1Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.N1Slider.ValueChangedFcn = createCallbackFcn(app, @N1SliderValueChanged, true);
            app.N1Slider.MinorTicks = [];
            app.N1Slider.Value = 10;
            app.N2SliderLabel = uilabel(app.ParametersPanel);
            app.N2SliderLabel.Text = 'N2';
            app.N2Slider = uislider(app.ParametersPanel);
            app.N2Slider.Limits = [1 10];
            app.N2Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.N2Slider.ValueChangedFcn = createCallbackFcn(app, @N2SliderValueChanged, true);
            app.N2Slider.MinorTicks = [];
            app.N2Slider.Value = 10;
            app.RSliderLabel = uilabel(app.ParametersPanel);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.ParametersPanel);
            app.RSlider.Limits = [0.02 0.05];
            app.RSlider.MajorTicks = [0.02 0.03 0.04 0.05];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.MinorTicks = [0.025 0.035 0.045];
            app.RSlider.Value = 0.025;
            app.VmaxSliderLabel = uilabel(app.ParametersPanel);
            app.VmaxSliderLabel.Text = 'Vmax';
            app.VmaxSlider = uislider(app.ParametersPanel);
            app.VmaxSlider.Limits = [0.1 0.5];
            app.VmaxSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5];
            app.VmaxSlider.ValueChangedFcn = createCallbackFcn(app, @VmaxSliderValueChanged, true);
            app.VmaxSlider.Value = 0.2;
            app.ImmobSliderLabel = uilabel(app.ParametersPanel);
            app.ImmobSliderLabel.Text = 'Immob';
            app.ImmobSlider = uislider(app.ParametersPanel);
            app.ImmobSlider.Limits = [0 1];
            app.ImmobSlider.MajorTicks = [0 0.1 0.3 0.5 0.7 0.9 1];
            app.ImmobSlider.MajorTickLabels = {'0', '0.1', '0.3', '0.5', '0.7', '0.9', '1'};
            app.ImmobSlider.ValueChangedFcn = createCallbackFcn(app, @ImmobSliderValueChanged, true);
            app.ImmobSlider.Value = 0.2;
            app.ActionsPanel = uipanel(app.MainLayout);
            app.ActionsPanel.Title = 'Actions';
            app.SimulateButton = uibutton(app.ActionsPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.AnimatePropagationButton = uibutton(app.ActionsPanel, 'push');
            app.AnimatePropagationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimatePropagationButtonPushed, true);
            app.AnimatePropagationButton.Enable = 'off';
            app.AnimatePropagationButton.Text = 'Animate Propagation';
            app.OpenModelButton = uibutton(app.ActionsPanel, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = '(Open Model)';
            app.SaveResultsButton = uibutton(app.ActionsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.UpdatePanel = uipanel(app.MainLayout);
            app.UpdatePanel.Title = 'New version of project is available!';
            app.UpdateButton = uibutton(app.UpdatePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotLayout = uigridlayout(app.RightPanel);
            app.PlotPanel = uipanel(app.PlotLayout);
            app.PlotPanel.Title = 'Plot';
            % Show the figure after all components are created
            app.PropagationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = propagationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PropagationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PropagationAppUIFigure)
        end
    end
end